classdef BatGUI1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        file_menu                       matlab.ui.container.Menu
        menu_file_open                  matlab.ui.container.Menu
        menu_file_flose                 matlab.ui.container.Menu
        Untitled_3                      matlab.ui.container.Menu
        menu_file_saveAs                matlab.ui.container.Menu
        edit_menu                       matlab.ui.container.Menu
        view_menu                       matlab.ui.container.Menu
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool5                   matlab.ui.container.toolbar.ToggleTool
        uitable2                        matlab.ui.control.Table
        uipanel13                       matlab.ui.container.Panel
        popupmenu10_DetetctBehavior     matlab.ui.control.DropDown
        uibuttongroup14                 matlab.ui.container.ButtonGroup
        checkbox38_MaskingByClutter     matlab.ui.control.CheckBox
        checkbox37_MaskingByConsps      matlab.ui.control.CheckBox
        text84                          matlab.ui.control.Label
        text83                          matlab.ui.control.Label
        popupmenu_TestMode              matlab.ui.control.DropDown
        checkboxAvoidObs                matlab.ui.control.CheckBox
        uipanel8                        matlab.ui.container.Panel
        editPreysNumber                 matlab.ui.control.EditField
        text32                          matlab.ui.control.Label
        editBatsNumber                  matlab.ui.control.EditField
        text31                          matlab.ui.control.Label
        editXYRes                       matlab.ui.control.EditField
        text7                           matlab.ui.control.Label
        editSampleTime                  matlab.ui.control.EditField
        editSimulationTime              matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        edit_swarm_start_pos_std        matlab.ui.control.EditField
        text82                          matlab.ui.control.Label
        popupmenuEnvironment            matlab.ui.control.DropDown
        editYroom                       matlab.ui.control.EditField
        editZroom                       matlab.ui.control.EditField
        editXroom                       matlab.ui.control.EditField
        text5                           matlab.ui.control.Label
        pushbuttonSaveParameters        matlab.ui.control.Button
        uipanel10                       matlab.ui.container.Panel
        checkboxIsPrey                  matlab.ui.control.CheckBox
        edit42TargetWingLength          matlab.ui.control.EditField
        text36                          matlab.ui.control.Label
        editPreyMaxAccelaration         matlab.ui.control.EditField
        editPreyNominalAccel            matlab.ui.control.EditField
        editPreyNominalVelocity         matlab.ui.control.EditField
        editPreyMaxVelocity             matlab.ui.control.EditField
        popupmenuPreyFlightType         matlab.ui.control.DropDown
        text27                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        pushbuttonLoadDefaultParams     matlab.ui.control.Button
        uipanel9                        matlab.ui.container.Panel
        edit54_BatMinVelocity           matlab.ui.control.EditField
        text77                          matlab.ui.control.Label
        editNominalAccel                matlab.ui.control.EditField
        popupmenuFlightType             matlab.ui.control.DropDown
        text13                          matlab.ui.control.Label
        editMaxVelocity                 matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        editNominalVelocity             matlab.ui.control.EditField
        editMaxAccel                    matlab.ui.control.EditField
        text11                          matlab.ui.control.Label
        text10                          matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        text81                          matlab.ui.control.Label
        edit60_ClutterGainDB            matlab.ui.control.EditField
        editCenterFreq                  matlab.ui.control.EditField
        editChirpSpan                   matlab.ui.control.EditField
        checkboxChirp                   matlab.ui.control.CheckBox
        checkboxVarianceSonar           matlab.ui.control.CheckBox
        edit53_Dist2Appraoch            matlab.ui.control.EditField
        edit52_Dist2Buzz                matlab.ui.control.EditField
        text76                          matlab.ui.control.Label
        text75                          matlab.ui.control.Label
        edit51_MinPulsePower            matlab.ui.control.EditField
        text74                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        editPulseTimeMax                matlab.ui.control.EditField
        editPulseTimeMin                matlab.ui.control.EditField
        editPRFMax                      matlab.ui.control.EditField
        editPRFMin                      matlab.ui.control.EditField
        editTransmitPower               matlab.ui.control.EditField
        editDetectRange                 matlab.ui.control.EditField
        editBeamWidth                   matlab.ui.control.EditField
        text19                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        text14                          matlab.ui.control.Label
        uipanel7                        matlab.ui.container.Panel
        checkboxSaveAnimation           matlab.ui.control.CheckBox
        uibuttongroup10                 matlab.ui.container.ButtonGroup
        edit_NumOfTrials                matlab.ui.control.EditField
        text62                          matlab.ui.control.Label
        text61_SaveDirectory            matlab.ui.control.Label
        text60_ScriptName               matlab.ui.control.Label
        pushbutton20_RunExp             matlab.ui.control.Button
        pushbutton18_ChooseSaveDir      matlab.ui.control.Button
        pushbutton17_ChooseTest         matlab.ui.control.Button
        uibuttongroup9                  matlab.ui.container.ButtonGroup
        pushbuttonPlotSonogram          matlab.ui.control.Button
        pushbuttonMyBatUmweltPlot       matlab.ui.control.Button
        textSummaryGroup10              matlab.ui.control.Label
        textSummaryGroup12              matlab.ui.control.Label
        textSummaryGroup11              matlab.ui.control.Label
        textSummaryGroup9               matlab.ui.control.Label
        textSummaryGroup8               matlab.ui.control.Label
        textSummaryGroup7               matlab.ui.control.Label
        textSummaryGroup6               matlab.ui.control.Label
        textSummaryGroup5               matlab.ui.control.Label
        textSummaryGroup4               matlab.ui.control.Label
        textSummaryGroup3               matlab.ui.control.Label
        textSummaryGroup2               matlab.ui.control.Label
        textSummaryGroup1               matlab.ui.control.Label
        uipanel11                       matlab.ui.container.Panel
        text43                          matlab.ui.control.Label
        text46                          matlab.ui.control.Label
        text42                          matlab.ui.control.Label
        text45                          matlab.ui.control.Label
        text44                          matlab.ui.control.Label
        text41                          matlab.ui.control.Label
        text40                          matlab.ui.control.Label
        text39                          matlab.ui.control.Label
        uibuttongroup7                  matlab.ui.container.ButtonGroup
        edit40PreyNumToPlot             matlab.ui.control.EditField
        text34                          matlab.ui.control.Label
        checkbox19AllPreysPlot          matlab.ui.control.CheckBox
        uibuttongroup6                  matlab.ui.container.ButtonGroup
        edit18BatNumToPlot              matlab.ui.control.EditField
        text33                          matlab.ui.control.Label
        checkboxAllBatsPlot             matlab.ui.control.CheckBox
        pushbuttonRunAnimation          matlab.ui.control.Button
        uibuttongroup4                  matlab.ui.container.ButtonGroup
        editEndTime                     matlab.ui.control.EditField
        text23                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        editStartTime                   matlab.ui.control.EditField
        checkboxTimeFilter              matlab.ui.control.CheckBox
        pushbuttonClearTimeFig          matlab.ui.control.Button
        pushbuttonPlotTimeGraph         matlab.ui.control.Button
        pushbuttonClearFigxy            matlab.ui.control.Button
        checkboxHoldOn                  matlab.ui.control.CheckBox
        pushbuttonPlotxy                matlab.ui.control.Button
        checkboxTimeHoldOn              matlab.ui.control.CheckBox
        xyPlot                          matlab.ui.control.UIAxes
        TimePlot                        matlab.ui.control.UIAxes
        uibuttongroup5                  matlab.ui.container.ButtonGroup
        textDataAvailabilty             matlab.ui.control.Label
        pushbuttonSaveData              matlab.ui.control.Button
        pushbuttonLoadData              matlab.ui.control.Button
        pushbuttonSendData              matlab.ui.control.Button
        pushbuttonRunSim                matlab.ui.control.Button
        uibuttongroup8                  matlab.ui.container.ButtonGroup
        uibuttongroup13                 matlab.ui.container.ButtonGroup
        checkbox35_PhantomCheckSim      matlab.ui.control.CheckBox
        checkbox34_PhantomEchoesFromConsFlag  matlab.ui.control.CheckBox
        edit59_NoiseLeveldB             matlab.ui.control.EditField
        checkbox33_ReactToBatsFlag      matlab.ui.control.CheckBox
        edit58_PulseDetectionTH         matlab.ui.control.EditField
        uibuttongroup12                 matlab.ui.container.ButtonGroup
        edit43_ProbToDetect             matlab.ui.control.EditField
        checkbox_MissDetectionProbFlag  matlab.ui.control.CheckBox
        checkbox29_LocalizationErrFlag  matlab.ui.control.CheckBox
        edit50_FwdMakingTime            matlab.ui.control.EditField
        text73                          matlab.ui.control.Label
        edit49_FwdMaskingTH             matlab.ui.control.EditField
        text69                          matlab.ui.control.Label
        checkbox22_FwdMaskingFlag       matlab.ui.control.CheckBox
        checkbox32_BiStatMode           matlab.ui.control.CheckBox
        uibuttongroup11                 matlab.ui.container.ButtonGroup
        checkbox_JARMode                matlab.ui.control.CheckBox
        edit_JARmem                     matlab.ui.control.EditField
        edit_JARDelta                   matlab.ui.control.EditField
        text79                          matlab.ui.control.Label
        text78                          matlab.ui.control.Label
        edit48_SIRneededTH              matlab.ui.control.EditField
        text67                          matlab.ui.control.Label
        popupmenuReceiverType           matlab.ui.control.DropDown
        text64                          matlab.ui.control.Label
        text63                          matlab.ui.control.Label
        text35                          matlab.ui.control.Label
    end

    
    methods (Access = private)
        function CheckAndHoldOnOff(app, Axes, HoldFlag)
            % this function decides weather the axes(Axes) should be hold on or off
            % according to the flag HoldFlag (o = hold off, 1= hold on)
            
            if HoldFlag
                hold(Axes,'on');
            else
                hold(Axes,'off');
            end
        end
        
        function [SimParams, BatFlightParams, BatSonarParams, TerrainParams, PreyFlightParams] = InitiateWithDefaultParams(app, FilePathName)
            %this functios load the parmaters saved in FilePAthe name to handles
            %Structure
            %
            %     load(FilePathName);
                Params = ReadDefaultParamsTable(FilePathName);
                % DefaultParameters is a MAT-file with the parameters neede to run the
                % simulation
                SimParams = Params.SimParams;
                BatFlightParams = Params.BatFlight;
                BatSonarParams = Params.BatSonar;
                TerrainParams = Params.Terrain;
                PreyFlightParams = Params.PreyFlight;
        end
        
        function checkbox2_Callback(app, hObject, eventdata, handles)
            % hObject    handle to checkboxAvoidObs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxAvoidObs
            ChBx1 = get(hObject,'Value');
            handles.SimParams.AvoidObsticleFlag = ChBx1;
            guidata(hObject,handles) % save the changes to the structure
        end
        
        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editSimulationTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit11_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editXYRes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit12_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editSampleTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit45DetectionTHdB_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit45DetectionTHdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulseDetectionTH = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end
        
        function edit45DetectionTHdB_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit45DetectionTHdB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit47_MemoryKHunted_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit47_MemoryKHunted (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit47_MemoryKHunted as text
            %        str2double(get(hObject,'String')) returns contents of edit47_MemoryKHunted as a double
            ed1 = get(hObject,'String');
            handles.BatFlightParams.NumOfSamePreyForAppraoach = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end
        
        function edit47_MemoryKHunted_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit47_MemoryKHunted (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function editPulsePower_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPulsePower (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function editResolution_Callback(app, hObject, eventdata, handles)
            % hObject    handle to editResolution (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editResolution as text
            %        str2double(get(hObject,'String')) returns contents of editResolution as a double
            ed1 = get(hObject,'String');
            handles.SimParams.xyResolution = str2double(ed1);
            guidata(hObject,handles);
        end
        
        function editResolution_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editResolution (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function editTotalTime_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listboxPLotBatNum_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listboxPLotBatNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbuttonSTOP_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbuttonSTOP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            return
        end
        
        function radiobutton26_ConspJamming_Callback(app, hObject, eventdata, handles)
            % hObject    handle to radiobutton26_ConspJamming (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.SimParams.MaskingByConsps = get(hObject,'Value');
            guidata(hObject,handles) % save the changes to the structure
        end
        
        function radiobutton27_ClutterMode_Callback(app, hObject, eventdata, handles)
            % hObject    handle to radiobutton27_ClutterMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.SimParams.MaskingByClutter = get(hObject,'Value');
            guidata(hObject,handles) % save the changes to the structure
        end
        
        function radiobuttonJAR_OnOff_Callback(app, hObject, eventdata, handles)
            % hObject    handle to radiobuttonJAR_OnOff (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.BatSonarParams.JARMode = get(hObject,'Value');
            guidata(hObject,handles);
        end
        
        function radiobuttonJAR_OnOff_KeyPressFcn(app, hObject, eventdata, handles)
            % hObject    handle to radiobuttonJAR_OnOff (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.UICONTROL)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            
            
            % --- Executes on button press in checkboxJARMode.
            handles.BatSonarParams.JARMode = get(hObject,'Value');
            guidata(hObject,handles);
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BatGUI1_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to BatGUI1 (see VARARGIN)
            
            %%%%%%%%
            %% Defaualt parameters for The Simulation
            % FileNameOfDefaultParams = 'DATA\DefaultParameters.mat';
            FileNameOfDefaultParams =  'DATA\DefaultParamsTable.xlsx';
            [handles.SimParams, handles.BatFlightParams, handles.BatSonarParams, ...
                handles.TerrainParams, handles.PreyFlightParams] = InitiateWithDefaultParams(app, FileNameOfDefaultParams);
            
            %     load('C:\Users\DELL\Documents\òåîø\ìéîåãéí\îç÷ø\MATLAB Bat Simulation\DATA\DefaultParameters');
            %     % DefaultParameters is a MAT-file with the parameters neede to run the
            %     % simulation
            %     handles.SimParams = Params.SimParams;
            %     handles.BatFlightParams = Params.BatFlight;
            %     handles.BatSonarParams = Params.BatSonar;
            %     handles.TerrainParams = Params.Terrain;
            %     handles.PreyFlightParams = Params.PreyFlight;
            
            %%% update the GUI with the default parameters %%%
            % SimParams
            set(handles.textDataAvailabilty,'String','DATA: NOT Available');
            set(handles.editSampleTime,'String',handles.SimParams.SampleTime);
            set(handles.editXYRes,'String',handles.SimParams.xyResolution);
            set(handles.editSimulationTime,'String',handles.SimParams.SimulationTime);
            set(handles.checkboxAvoidObs,'Value',handles.BatFlightParams.AvoidObsFlag);
            set(handles.checkboxIsPrey,'Value',handles.SimParams.IsPreyFlag);
            set(handles.editPreysNumber,'Value',handles.SimParams.TotalPreysNumber);
            set(handles.editBatsNumber,'Value',handles.SimParams.TotalBatsNumber);
            
            set(handles.popupmenu_TestMode, 'Value', find(strcmp(handles.popupmenu_TestMode.String, handles.SimParams.TestMode)) );
            
            set(handles.editXroom,'String',handles.TerrainParams.Xmax);
            set(handles.editYroom,'String',handles.TerrainParams.Ymax);
            set(handles.editZroom,'String',handles.TerrainParams.Zmax);
            set(handles.edit_swarm_start_pos_std, 'String', handles.SimParams.swarm_initial_ybat_std);
            
            % set(handles.popupmenuEnvironment,'Value',2); % Empty Room
            [handles.Terrain, handles.TerrainParams.Xmax,...
                handles.TerrainParams.Ymax, handles.TerrainParams.Zmax,...
                handles.TerrainParams.Xmin, handles.TerrainParams.Ymin, handles.TerrainParams.Zmin] = ...
                BuildEnvironment(handles.TerrainParams, handles.SimParams.xyResolution,handles.TerrainParams.Type);
            set(handles.editZroom,'String',handles.TerrainParams.Zmax);
            set(handles.editXroom,'String',handles.TerrainParams.Xmax);
            set(handles.editYroom,'String',handles.TerrainParams.Ymax);
            set(handles.popupmenuEnvironment, 'Value',find(strcmp(handles.popupmenuEnvironment.String, handles.TerrainParams.Type)));
            
            if strcmp(handles.TerrainParams.Type, 'Free Space')
                set(handles.editXroom,'Enable', 'off');
                set(handles.editYroom,'Enable', 'off');
                set(handles.editZroom,'Enable', 'off');
            end % if
            
            set(handles.editBatsNumber,'String',handles.SimParams.TotalBatsNumber);
            set(handles.editPreysNumber,'String',handles.SimParams.TotalPreysNumber);
            
            % BatFlighParams
            set(handles.editMaxVelocity,'String',handles.BatFlightParams.MaxVelocity);
            set(handles.editNominalVelocity,'String',handles.BatFlightParams.NominalVelocity);
            set(handles.editMaxAccel,'String',handles.BatFlightParams.MaxAccelaration);
            set(handles.editNominalAccel,'String',handles.BatFlightParams.NominalAccel);
            % set(handles.checkboxPreyHunting,'Value',handles.BatFlightParams.PreyHuntingFlag);
            set(handles.popupmenuFlightType, 'Value',find(strcmp(handles.popupmenuFlightType.String, handles.BatFlightParams.FlightTypeFlag)) );
            
            
            % BatSonarParams
            set(handles.editBeamWidth,'String',handles.BatSonarParams.BatBeamWidth);
            set(handles.editDetectRange,'String',handles.BatSonarParams.BatDetectionRange);
            set(handles.editTransmitPower,'String',handles.BatSonarParams.PulsePower);
            set(handles.editPRFMin,'String',handles.BatSonarParams.BatNominalPRF);
            set(handles.editPRFMax,'String',handles.BatSonarParams.BatMaxPRF);
            set(handles.editPulseTimeMin,'String',handles.BatSonarParams.PulseTimeShort);
            set(handles.editPulseTimeMax,'String',handles.BatSonarParams.PulseTimeLong);
            set(handles.editCenterFreq,'String',handles.BatSonarParams.CenterFreq);
            set(handles.edit58_PulseDetectionTH,'String',handles.BatSonarParams.PulseDetectionTH);
            % set(handles.editPulsePower,'String',handles.BatSonarParams.PulsePower);
            set(handles.edit59_NoiseLeveldB,'String',handles.BatSonarParams.NoiseLeveldB);
            set(handles.edit42TargetWingLength,'String',handles.BatSonarParams.TargetWingLength);
            set(handles.checkbox_MissDetectionProbFlag,'Value',handles.BatSonarParams.MissDetectionProbFlag);
            set(handles.edit43_ProbToDetect,'String',handles.BatSonarParams.ProbToDetect);
            % set(handles.edit45DetectionTHdB,'String',handles.BatSonarParams.PulseDetectionTH);
            % set(handles.edit46_MemorySize,'String',handles.BatFlightParams.HuntedMemorySize);
            % set(handles.edit47_MemoryKHunted,'String',handles.BatFlightParams.NumOfSamePreyForAppraoach);
            set(handles.edit48_SIRneededTH,'String',handles.BatSonarParams.Signal2InterferenceRatio);
            set(handles.edit49_FwdMaskingTH,'String',handles.BatSonarParams.MaskingFwdSIRth);
            set(handles.edit50_FwdMakingTime,'String',handles.BatSonarParams.MaskingFwdTime);
            set(handles.edit51_MinPulsePower,'String',handles.BatSonarParams.PulseMinPower);
            set(handles.edit52_Dist2Buzz,'String',handles.BatFlightParams.DistanceToBuzz);
            set(handles.edit53_Dist2Appraoch,'String',handles.BatFlightParams.DistanceFromPreyToApproach);
            set(handles.edit54_BatMinVelocity,'String',handles.BatFlightParams.MinVelocity);
            set(handles.checkbox29_LocalizationErrFlag, 'Value', handles.BatSonarParams.LocalizationErrFlag);
            
            set(handles.checkbox37_MaskingByConsps, 'Value', handles.SimParams.MaskingByConsps);
            set(handles.checkbox38_MaskingByClutter, 'Value', handles.SimParams.MaskingByClutter);
            set(handles.radiobutton28_Acoustics, 'Value', handles.SimParams.AcousticsCalcFlag);
            
            % set(handles.popupmenuReceiverType,'String',handles.BatSonarParams.ReceiverType);
            
            
            % PreyFlightParams
            set(handles.editPreyMaxVelocity,'String',handles.PreyFlightParams.PmaxVelocity);
            set(handles.editPreyNominalVelocity,'String',handles.PreyFlightParams.PNominalVelocity);
            set(handles.editPreyMaxAccelaration,'String',handles.PreyFlightParams.PMaxAccelaration);
            set(handles.editPreyNominalAccel,'String',handles.PreyFlightParams.PNominalAccel);
            set(handles.popupmenuPreyFlightType, 'Value', find(strcmp(handles.popupmenuPreyFlightType.String, handles.PreyFlightParams.PFlightTypeFlag)) );
            
            % multi Prey an Bats
            handles.DataToPlot.PlotAllPreysFlag = handles.checkbox19AllPreysPlot.Value;
            handles.DataToPlot.PlotAllBatsFlag = handles.checkboxAllBatsPlot.Value;
            handles.edit18BatNumToPlot.Value = 1;
            handles.DataToPlot.BatNumToPlot = handles.edit18BatNumToPlot.Value;
            handles.edit40PreyNumToPlot.Value = 1;
            handles.DataToPlot.PreyNumToPlot = handles.edit40PreyNumToPlot.Value;
            % X-Y Plot Paramsha
             handles.DataToPlot.PreyPositionFlag = handles.radiobuttonPeryPosFLag.Value;
             handles.DataToPlot.TerrainFlag = handles.radiobuttonTerrain.Value;
             handles.DataToPlot.BatPosFlag = handles.radiobuttonBatPos.Value;
             handles.DataToPlot.SonarPosFlag = handles.radiobuttonSonarPos.Value;
             handles.DataToPlot.ManPosFlag = handles.radiobuttonManuevresPos.Value;
             handles.DataToPlot.TerrainFindFlag = handles.radiobuttonTerrainFind.Value;
             handles.DataToPlot.EndTimeFilter = handles.editEndTime.Value;
             handles.DataToPlot.StartTimeFilter = handles.editStartTime.Value;
             handles.DataToPlot.TimeFilterFlag = handles.checkboxTimeFilter.Value;
             handles.DataToPlot.CatchesPosFlag = handles.radiobuttonCatchesPositions.Value;
             set(handles.checkboxHoldOn,'Value',1);
             handles.handleXYBatPosLine =[];
             % Time Plot Params
             handles.DataToPlot.HuntingManuevers =  handles.radiobuttonHuntMan.Value;
             handles.DataToPlot.FlightVelocity =  handles.radiobuttonFlightVelocity.Value;
             handles.DataToPlot.SonarTimes =  handles.radiobuttonSonarTimes.Value;
             handles.DataToPlot.PulseDuration=  handles.radiobuttonPulseDur.Value;
             handles.DataToPlot.SonarPulses =  handles.radiobuttonRadialACcel.Value;
             handles.DataToPlot.PRFVec = handles.radiobuttonPRFVec.Value;
             handles.DataToPlot.Bat2PreyDistFlag = handles.radiobuttonBat2PreyDistFlag.Value ;
             handles.DataToPlot.Bat2PreyAngleFlag =  handles.radiobutton15Bat2PreyAngleFlag.Value;
             handles.DataToPlot.PreyFindsFlag = handles.radiobuttonPreyFindsFlag.Value;
             handles.DataToPlot.BatAllPulsesFlag = handles.radiobuttonAllSonarPulses.Value;
             handles.DataToPlot.ObsEchosFlag = handles.radiobuttonObsEchos.Value;
             handles.DataToPlot.InterferencePlotFlag =  handles.radiobutton20_PlotInterferenceFlag.Value;
             handles.DataToPlot.PreyEchosFlag = handles.radiobuttonPreyEchos.Value;
             handles.DataToPlot.FlightVelocity = handles.radiobuttonFlightVelocity.Value;
             set(handles.checkboxTimeHoldOn,'Value',1);
             hold(handles.TimePlot, 'on')
            
             % Animation
            handles.SaveAminationFlag = 0;
            
            guidata(hObject,handles); % save the changes to the structure
            
            % Data to Analyze
            handles.DataToAnalyze.BatPos = [];
            handles.DataToAnalyze.BatDirection = [];
            handles.DataToAnalyze.BatDirectionChange = [];
            handles.DataToAnalyze.SonarTimesVec = [];
            handles.DataToAnalyze.ObsManueverFlag = [];
            handles.DataToAnalyze.HuntingFlag = [];
            handles.DataToAnalyze.ObsticlesFound = [];
            handles.DataToAnalyze.PRFVec = [];
            handles.DataToAnalyze.PulseWidthVec = [];
            handles.DataToAnalyze.CatchPreyPos = [];
            handles.ZoomHandle = zoom;
            guidata(hObject,handles); % save the changes to the structure


            % Choose default command line output for BatGUI1
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox19AllPreysPlot
        function checkbox19AllPreysPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox19AllPreysPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ChBx1 = get(hObject,'Value');
            handles.DataToPlot.PlotAllPreysFlag = ChBx1;
            guidata(hObject,handles)
        end

        % Value changed function: checkbox22_FwdMaskingFlag
        function checkbox22_FwdMaskingFlag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox22_FwdMaskingFlag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox22_FwdMaskingFlag
            ChBx1 = get(hObject,'Value');
            handles.BatSonarParams.MaskingFwdFlag = ChBx1;
            guidata(hObject,handles);
        end

        % Value changed function: checkbox29_LocalizationErrFlag
        function checkbox29_LocalizationErrFlag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox29_LocalizationErrFlag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ChBx1 = get(hObject,'Value');
                handles.BatSonarParams.LocalizationErrFlag = ChBx1;
                guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: checkbox32_BiStatMode
        function checkbox32_BiStatMode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox32_BiStatMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.BatSonarParams.BiStatMode = get(hObject,'Value');
            guidata(hObject,handles);
        end

        % Value changed function: checkbox33_ReactToBatsFlag
        function checkbox33_ReactToBatsFlag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox33_ReactToBatsFlag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.BatFlightParams.ReactToBatsFlag = get(hObject,'Value');
            guidata(hObject,handles);
        end

        % Value changed function: checkbox34_PhantomEchoesFromConsFlag
        function checkbox34_PhantomEchoesFromConsFlag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox34_PhantomEchoesFromConsFlag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.BatSonarParams.PhantomEchoesFromConsFlag = get(hObject,'Value');
            guidata(hObject,handles);
        end

        % Value changed function: checkbox35_PhantomCheckSim
        function checkbox35_PhantomCheckSim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox35_PhantomCheckSim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox35_PhantomCheckSim
            handles.BatSonarParams.PhantomEchoesCheckSimiliarity = get(hObject,'Value');
            guidata(hObject,handles);
        end

        % Value changed function: checkbox37_MaskingByConsps
        function checkbox37_MaskingByConsps_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox37_MaskingByConsps (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.SimParams.MaskingByConsps = get(hObject,'Value');
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: checkbox38_MaskingByClutter
        function checkbox38_MaskingByClutter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox38_MaskingByClutter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.SimParams.MaskingByClutter = get(hObject,'Value');
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: checkboxAllBatsPlot
        function checkboxAllBatsPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxAllBatsPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ChBx1 = get(hObject,'Value');
            handles.DataToPlot.PlotAllBatsFlag = ChBx1;
            guidata(hObject,handles)
        end

        % Value changed function: checkboxAvoidObs
        function checkboxAvoidObs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxAvoidObs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxAvoidObs
            ChBx1 = get(hObject,'Value');
            handles.BatFlightParams.AvoidObsFlag = ChBx1;
            guidata(hObject,handles);
        end

        % Value changed function: checkboxChirp
        function checkboxChirp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxChirp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                ChBx1 = get(hObject,'Value');
                handles.BatSonarParams.ChirpFlag = ChBx1;
                guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: checkboxHoldOn
        function checkboxHoldOn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxHoldOn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxHoldOn
            h1 = handles.xyPlot;
            c1 = get(hObject,'Value');
            CheckAndHoldOnOff(app, h1,c1);
            % if c1
            %     hold(h1,'on');
            % else
            %     hold(h1,'off');
            % end
            guidata(hObject, handles);
        end

        % Value changed function: checkboxIsPrey
        function checkboxIsPrey_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxIsPrey (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxIsPrey
            ChBx1 = get(hObject,'Value');
            handles.SimParams.IsPreyFlag = ChBx1;
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: checkboxSaveAnimation
        function checkboxSaveAnimation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxSaveAnimation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ChBx1 = get(hObject,'Value');
            handles.SaveAminationFlag = ChBx1;
            guidata(hObject,handles);
        end

        % Value changed function: checkboxTimeFilter
        function checkboxTimeFilter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxTimeFilter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxTimeFilter
            ChBx1 = get(hObject,'Value');
            handles.DataToPlot.TimeFilterFlag = ChBx1;
            guidata(hObject,handles)
        end

        % Value changed function: checkboxTimeHoldOn
        function checkboxTimeHoldOn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkboxTimeHoldOn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h2 = handles.TimePlot;
            c2 = get(hObject,'Value');
            CheckAndHoldOnOff(app, h2,c2);
            
            guidata(hObject, handles);
        end

        % Value changed function: checkbox_JARMode
        function checkbox_JARMode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_JARMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.BatSonarParams.JARMode = get(hObject,'Value');
            guidata(hObject,handles);
        end

        % Value changed function: checkbox_MissDetectionProbFlag
        function checkbox_MissDetectionProbFlag_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_MissDetectionProbFlag (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ChBx1 = get(hObject,'Value');
            handles.BatSonarParams.MissDetectionProbFlag = ChBx1;
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit18BatNumToPlot
        function edit18BatNumToPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit18BatNumToPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hied1 = get(hObject,'String');
            ed1 = get(hObject,'String');
            handles.DataToPlot.BatNumToPlot = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structurets: get(hObject,'String') returns contents of edit18BatNumToPlot as text
        end

        % Value changed function: edit40PreyNumToPlot
        function edit40PreyNumToPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit40PreyNumToPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit40PreyNumToPlot as text
            %        str2double(get(hObject,'String')) returns contents of edit40PreyNumToPlot as a double
            ed1 = get(hObject,'String');
            handles.DataToPlot.PreyNumToPlot = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: edit42TargetWingLength
        function edit42TargetWingLength_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit42TargetWingLength (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit42TargetWingLength as text
            %        str2double(get(hObject,'String')) returns contents of edit42TargetWingLength as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.TargetWingLength = str2double(ed1);
            guidata(hObject,handles)
        end

        % Value changed function: edit43_ProbToDetect
        function edit43_ProbToDetect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit43_ProbToDetect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit43_ProbToDetect as text
            %        str2double(get(hObject,'String')) returns contents of edit43_ProbToDetect as a double
            
            ed1 = get(hObject,'String');
            handles.BatSonarParams.ProbToDetect = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit48_SIRneededTH
        function edit48_SIRneededTH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit48_SIRneededTH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit48_SIRneededTH as text
            %        str2double(get(hObject,'String')) returns contents of edit48_SIRneededTH as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.Signal2InterferenceRatio = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit49_FwdMaskingTH
        function edit49_FwdMaskingTH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit49_FwdMaskingTH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit49_FwdMaskingTH as text
            %        str2double(get(hObject,'String')) returns contents of edit49_FwdMaskingTH as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.MaskingFwdSIRth = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit50_FwdMakingTime
        function edit50_FwdMakingTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit50_FwdMakingTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit50_FwdMakingTime as text
            %        str2double(get(hObject,'String')) returns contents of edit50_FwdMakingTime as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.MaskingFwdTime = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit51_MinPulsePower
        function edit51_MinPulsePower_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit51_MinPulsePower (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit51_MinPulsePower as text
            %        str2double(get(hObject,'String')) returns contents of edit51_MinPulsePower as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulseMinPower = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit52_Dist2Buzz
        function edit52_Dist2Buzz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit52_Dist2Buzz (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit52_Dist2Buzz as text
            %        str2double(get(hObject,'String')) returns contents of edit52_Dist2Buzz as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.DistanceToBuzz = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit53_Dist2Appraoch
        function edit53_Dist2Appraoch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit53_Dist2Appraoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit53_Dist2Appraoch as text
            %        str2double(get(hObject,'String')) returns contents of edit53_Dist2Appraoch as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.DistanceFromPreyToApproach = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit54_BatMinVelocity
        function edit54_BatMinVelocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit54_BatMinVelocity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit54_BatMinVelocity as text
            %        str2double(get(hObject,'String')) returns contents of edit54_BatMinVelocity as a double
            ed1 = get(hObject,'String');
            handles.BatFlightParams.MinVelocity = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit58_PulseDetectionTH
        function edit58_PulseDetectionTH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit58_PulseDetectionTH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit58_PulseDetectionTH as text
            %        str2double(get(hObject,'String')) returns contents of edit58_PulseDetectionTH as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulseDetectionTH = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: edit59_NoiseLeveldB
        function edit59_NoiseLeveldB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit59_NoiseLeveldB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit59_NoiseLeveldB as text
            %        str2double(get(hObject,'String')) returns contents of edit59_NoiseLeveldB as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.NoiseLeveldB = str2double(ed1);
            guidata(hObject,handles)
        end

        % Value changed function: edit60_ClutterGainDB
        function edit60_ClutterGainDB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit60_ClutterGainDB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit60_ClutterGainDB as text
            %        str2double(get(hObject,'String')) returns contents of edit60_ClutterGainDB as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.ClutterGainDB = str2double(ed1);
            guidata(hObject,handles)
        end

        % Value changed function: editBatsNumber
        function editBatsNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editBatsNumber (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ed1 = get(hObject,'String');
            handles.SimParams.TotalBatsNumber = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editBeamWidth
        function editBeamWidth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editBeamWidth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editBeamWidth as text
            %        str2double(get(hObject,'String')) returns contents of editBeamWidth as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.BatBeamWidth = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editCenterFreq
        function editCenterFreq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editCenterFreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editCenterFreq as text
            %        str2double(get(hObject,'String')) returns contents of editCenterFreq as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.CenterFreq = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editChirpSpan
        function editChirpSpan_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editChirpSpan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editChirpSpan as text
            %        str2double(get(hObject,'String')) returns contents of editChirpSpan as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.ChirpSpan = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editDetectRange
        function editDetectRange_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editDetectRange (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editDetectRange as text
            %        str2double(get(hObject,'String')) returns contents of editDetectRange as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.BatDetectionRange = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editEndTime
        function editEndTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editEndTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editEndTime as text
            %        str2double(get(hObject,'String')) returns contents of editEndTime as a double
            ed1 = get(hObject,'String');
            handles.DataToPlot.EndTimeFilter = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editMaxAccel
        function editMaxAccel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editMaxAccel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editMaxAccel as text
            %        str2double(get(hObject,'String')) returns contents of editMaxAccel as a double
            ed1 = get(hObject,'String');
            handles.BatFlightParams.MaxAccelaration = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editMaxVelocity
        function editMaxVelocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editMaxVelocity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ed1 = get(hObject,'String');
            handles.BatFlightParams.MaxVelocity = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editNominalAccel
        function editNominalAccel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editNominalAccel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editNominalAccel as text
            %        str2double(get(hObject,'String')) returns contents of editNominalAccel as a double
            ed1 = get(hObject,'String');
            handles.BatFlightParams.NominalAccel = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editNominalVelocity
        function editNominalVelocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editNominalVelocity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editNominalVelocity as text
            %        str2double(get(hObject,'String')) returns contents of editNominalVelocity as a double
            ed1 = get(hObject,'String');
            handles.BatFlightParams.NominalVelocity = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editPRFMax
        function editPRFMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPRFMax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPRFMax as text
            %        str2double(get(hObject,'String')) returns contents of editPRFMax as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.BatMaxPRF = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editPRFMin
        function editPRFMin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPRFMin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPRFMin as text
            %        str2double(get(hObject,'String')) returns contents of editPRFMin as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.BatNominalPRF = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editPreyMaxAccelaration
        function editPreyMaxAccelaration_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPreyMaxAccelaration (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPreyMaxAccelaration as text
            %        str2double(get(hObject,'String')) returns contents of editPreyMaxAccelaration as a double
            ed1 = get(hObject,'String');
            handles.PreyFlightParams.PMaxAccelaration = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editPreyMaxVelocity
        function editPreyMaxVelocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPreyMaxVelocity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPreyMaxVelocity as text
            %        str2double(get(hObject,'String')) returns contents of editPreyMaxVelocity as a double
            ed1 = get(hObject,'String');
            handles.PreyFlightParams.PMaxVelocity = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editPreyNominalAccel
        function editPreyNominalAccel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPreyNominalAccel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPreyNominalAccel as text
            %        str2double(get(hObject,'String')) returns contents of editPreyNominalAccel as a double
            ed1 = get(hObject,'String');
            handles.PreyFlightParams.PNominalAccelaration = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editPreyNominalVelocity
        function editPreyNominalVelocity_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPreyNominalVelocity (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPreyNominalVelocity as text
            %        str2double(get(hObject,'String')) returns contents of editPreyNominalVelocity as a double
            ed1 = get(hObject,'String');
            handles.PreyFlightParams.PNominalVelocity = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editPreysNumber
        function editPreysNumber_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPreysNumber (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPreysNumber as text
            %        str2double(get(hObject,'String')) returns contents of editPreysNumber as a double
            ed1 = get(hObject,'String');
            handles.SimParams.TotalPreysNumber = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editPulseTimeMax
        function editPulseTimeMax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPulseTimeMax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPulseTimeMax as text
            %        str2double(get(hObject,'String')) returns contents of editPulseTimeMax as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulseTimeLong = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editPulseTimeMin
        function editPulseTimeMin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editPulseTimeMin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editPulseTimeMin as text
            %        str2double(get(hObject,'String')) returns contents of editPulseTimeMin as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulseTimeShort = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editSampleTime
        function editSampleTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editSimulationTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editSimulationTime as text
            %        str2double(get(hObject,'String')) returns contents of editSimulationTime as a double
            ed1 = get(hObject,'String');
            handles.SimParams.SampleTime = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editSimulationTime
        function editSimulationTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editResolution (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editResolution as text
            %        str2double(get(hObject,'String')) returns contents of editResolution as a double
            ed1 = get(hObject,'String');
            handles.SimParams.SimulationTime = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editStartTime
        function editStartTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editStartTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editStartTime as text
            %        str2double(get(hObject,'String')) returns contents of editStartTime as a double
            ed1 = get(hObject,'String');
            handles.DataToPlot.StartTimeFilter = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editTransmitPower
        function editTransmitPower_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editTransmitPower (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editTransmitPower as text
            %        str2double(get(hObject,'String')) returns contents of editTransmitPower as a double
            ed1 = get(hObject,'String');
            handles.BatSonarParams.PulsePower = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: editXYRes
        function editXYRes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editXYRes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editXYRes as text
            %        str2double(get(hObject,'String')) returns contents of editXYRes as a double
            ed1 = get(hObject,'String');
            handles.SimParams.xyResolution = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: editXroom
        function editXroom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editXroom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editXroom as text
            %        str2double(get(hObject,'String')) returns contents of editXroom as a double
            ed1 = get(hObject,'String');
            handles.TerrainParams.Xmax = str2double(ed1);
            [handles.Terrain, handles.TerrainParams.Xmax,...
                handles.TerrainParams.Ymax, handles.TerrainParams.Zmax,...
                handles.TerrainParams.Xmin, handles.TerrainParams.Ymin, handles.TerrainParams.Zmin] =...
                BuildEnvironment(handles.TerrainParams, handles.SimParams.xyResolution,...
                handles.TerrainParams.Type);
            guidata(hObject,handles);
        end

        % Value changed function: editYroom
        function editYroom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editYroom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editYroom as text
            %        str2double(get(hObject,'String')) returns contents of editYroom as a double
            ed1 = get(hObject,'String');
            handles.TerrainParams.Ymax = str2double(ed1);
            [handles.Terrain, handles.TerrainParams.Xmax,...
                handles.TerrainParams.Ymax, handles.TerrainParams.Zmax,...
                handles.TerrainParams.Xmin, handles.TerrainParams.Ymin, handles.TerrainParams.Zmin] =...
                BuildEnvironment(handles.TerrainParams, handles.SimParams.xyResolution,...
                handles.TerrainParams.Type);
            guidata(hObject,handles);
        end

        % Value changed function: editZroom
        function editZroom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editZroom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editZroom as text
            %        str2double(get(hObject,'String')) returns contents of editZroom as a double
            ed1 = get(hObject,'String');
            handles.TerrainParams.Zmax = str2double(ed1);
            [handles.Terrain, handles.TerrainParams.Xmax,...
                handles.TerrainParams.Ymax, handles.TerrainParams.Zmax,...
                handles.TerrainParams.Xmin, handles.TerrainParams.Ymin, handles.TerrainParams.Zmin] =...
                BuildEnvironment(handles.TerrainParams, handles.SimParams.xyResolution,...
                handles.TerrainParams.Type);
            guidata(hObject,handles);
        end

        % Value changed function: edit_JARDelta
        function edit_JARDelta_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_JARDelta (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ed1 = get(hObject,'String');
            handles.BatSonarParams.JARDelta = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit_JARmem
        function edit_JARmem_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_JARmem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ed1 = get(hObject,'String');
            handles.BatSonarParams.JARMem = str2double(ed1);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: edit_swarm_start_pos_std
        function edit_swarm_start_pos_std_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_swarm_start_pos_std (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_swarm_start_pos_std as text
            %        str2double(get(hObject,'String')) returns contents of edit_swarm_start_pos_std as a double
            ed1 = get(hObject,'String');
            handles.SimParams.swarm_initial_ybat_std = str2double(ed1);
            guidata(hObject,handles);
        end

        % Value changed function: popupmenu10_DetetctBehavior
        function popupmenu10_DetetctBehavior_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu10_DetetctBehavior (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            switch popupValue
                case 'Prey'
                    handles.SimParams.DetectPrey = 1;
                    handles.SimParams.DetectConsps = 0;
                    handles.SimParams.DetectObs = 0;
                case 'Conspecifics'
                    handles.SimParams.DetectPrey = 0;
                    handles.SimParams.DetectConsps = 1;
                    handles.SimParams.DetectObs = 0;
                case 'Obstacles'
                    handles.SimParams.DetectPrey = 0;
                    handles.SimParams.DetectConsps = 0;
                    handles.SimParams.DetectObs = 1;
            
            end % switch
            
            guidata(hObject,handles);
        end

        % Value changed function: popupmenuEnvironment
        function popupmenuEnvironment_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuEnvironment (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuEnvironment contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuEnvironment
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            handles.TerrainParams.Type = popupValue;
            [handles.Terrain, handles.TerrainParams.Xmax,...
                handles.TerrainParams.Ymax, handles.TerrainParams.Zmax,...
                handles.TerrainParams.Xmin, handles.TerrainParams.Ymin, handles.TerrainParams.Zmin] =...
                BuildEnvironment(handles.TerrainParams, handles.SimParams.xyResolution,...
                handles.TerrainParams.Type);
            % Control t size room buttons
            % if strcmp(handles.TerrainParams.Type, 'Free Space')
            %     set(handles.editXroom,'Enable', 'off');
            %     set(handles.editYroom,'Enable', 'off');
            %     set(handles.editZroom,'Enable', 'off');
            % else
            %    set(handles.editXroom,'Enable', 'on');
            %    set(handles.editYroom,'Enable', 'on');
            %    set(handles.editZroom,'Enable', 'on');
            % end % if
            
            
            handles.TerrainParams.Type = popupValue;
            guidata(hObject, handles);
        end

        % Value changed function: popupmenuFlightType
        function popupmenuFlightType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuFlightType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            handles.BatFlightParams.FlightTypeFlag = popupValue;
            guidata(hObject,handles);
        end

        % Value changed function: popupmenuPreyFlightType
        function popupmenuPreyFlightType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuPreyFlightType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuPreyFlightType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuPreyFlightType
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            handles.PreyFlightParams.PFlightTypeFlag = popupValue;
            guidata(hObject,handles);
        end

        % Value changed function: popupmenuReceiverType
        function popupmenuReceiverType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuReceiverType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuReceiverType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuReceiverType
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            % handles.BatSonarParams.ReceiverType  = popupValue;
            %%%% Somthing Wrong with CorrealtionDetector .. so that's the solution
            switch popupValue
                case 'MaxPowerTH'
                    handles.BatSonarParams.ReceiverType = 'MaxPowerTH';
            
                case 'FilterBank'
                    handles.BatSonarParams.ReceiverType = 'FilterBank';
            
                case 'Correlation'
                    handles.BatSonarParams.ReceiverType = 'CorrelationDetector';
            
                case 'TimeSequenceTH'
                    handles.BatSonarParams.ReceiverType = 'TimeSequenceTH';
            
                case 'NoMasking'
                    handles.BatSonarParams.ReceiverType = 'NoMasking';
            
            end % switch popupValue
            guidata(hObject,handles) % save the changes to the structure
        end

        % Value changed function: popupmenu_TestMode
        function popupmenu_TestMode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_TestMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_TestMode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_TestMode
            PopupContents = cellstr(get(hObject,'String'));
            popupValue= PopupContents{get(hObject,'Value')};
            handles.SimParams.TestMode = popupValue;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton20_RunExp
        function pushbutton20_RunExp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton20_RunExp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PathFileName = handles.Experiment.ScriptTOrun ;
            run(PathFileName)
        end

        % Button pushed function: pushbuttonClearFigxy
        function pushbuttonClearFigxy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonClearFigxy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h1 = handles.xyPlot;
            cla(h1);
        end

        % Button pushed function: pushbuttonClearTimeFig
        function pushbuttonClearTimeFig_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonClearTimeFig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h2 = handles.TimePlot;
            cla(h2);
        end

        % Button pushed function: pushbuttonLoadData
        function pushbuttonLoadData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonLoadData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % [FileName,PathName,FilterIndex] = uigetfile('C:\Users\DELL\Documents\òåîø\ìéîåãéí\îç÷ø\MATLAB Bat Simulation\DATA\*.mat');
            [FileName,PathName,FilterIndex] = uigetfile('DATA\*.mat');
            
            PathFileName = [PathName,FileName];
            load(PathFileName);
            LoadedBatDATA = load(PathFileName);
            try
                handles.DataToAnalyze = LoadedBatDATA.BatDATA;
            catch
                handles.DataToAnalyze = LoadedBatDATA.DataToAnalyze; % for Preleimnary results that are saves as (xxx).DataToAnalyze
            end % try
            set(handles.textDataAvailabilty,'String',['DATA: ', FileName]);
            guidata(hObject,handles) % save the changes to the structure
        end

        % Button pushed function: pushbuttonLoadDefaultParams
        function pushbuttonLoadDefaultParams_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonLoadDefaultParams (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            BatGUI1_OpeningFcn();
        end

        % Button pushed function: pushbuttonMyBatUmweltPlot
        function pushbuttonMyBatUmweltPlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonMyBatUmweltPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles.DataToAnalyze, handles.DataToPlot
            MyBatUmweltOnLine( handles.DataToAnalyze.BAT(handles.DataToPlot.BatNumToPlot) ,...
                handles.DataToAnalyze.AllParams)
        end

        % Button pushed function: pushbuttonPlotSonogram
        function pushbuttonPlotSonogram_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlotSonogram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            MySonogramPlot(handles.DataToAnalyze, handles.DataToPlot);
        end

        % Button pushed function: pushbuttonPlotTimeGraph
        function pushbuttonPlotTimeGraph_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlotTimeGraph (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.ZoomHandle,'ActionPostCallback',...
                {@MyZoom1,handles.xyPlot, handles.TimePlot, handles.DataToPlot, handles.DataToAnalyze,...
                 handles.SimParams, handles.TerrainParams, handles.Terrain});
            ZoomFlag = 0;
            IDn = [];
            h1 = handles.xyPlot; % the axes of the current fig
            c1 = get(handles.checkboxTimeHoldOn,'Value'); % the flag wether to hold on
            CheckAndHoldOnOff(app, h1,c1);
            MyTimePlot(handles.TimePlot,handles.DataToAnalyze,handles.DataToPlot,...
                handles.SimParams, handles.TerrainParams, handles.Terrain, ZoomFlag, IDn)
        end

        % Button pushed function: pushbuttonPlotxy
        function pushbuttonPlotxy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonPlotxy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.ZoomHandle,'ActionPostCallback',...
                {@MyZoom1,handles.xyPlot, handles.TimePlot, handles.DataToPlot, handles.DataToAnalyze,...
                 handles.SimParams, handles.TerrainParams, handles.Terrain});
            ZoomFlag= 0;
            IDn = [];
            h1 = handles.xyPlot; % the axes of the current fig
            c1 = get(handles.checkboxHoldOn,'Value'); % the flag wether to hold on
            CheckAndHoldOnOff(app, h1,c1);
                MyXYPlot(handles.xyPlot,handles.DataToAnalyze,handles.DataToPlot,...
                handles.SimParams, handles.TerrainParams, handles.Terrain, ZoomFlag, IDn);
            guidata(hObject,handles);
        end

        % Button pushed function: pushbuttonRunAnimation
        function pushbuttonRunAnimation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRunAnimation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % BatAnimation(handles.DataToAnalyze, handles.SimParams );
            MyBatAnimation(handles.DataToAnalyze, handles.DataToPlot, handles.SaveAminationFlag, handles.Terrain);
        end

        % Button pushed function: pushbuttonRunSim
        function pushbuttonRunSim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonRunSim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % set(hObject,'String','WAIT');
            set(hObject,'Enable','off');
            AllParameters.SimParams = handles.SimParams;
            AllParameters.BatFlightParams = handles.BatFlightParams;
            AllParameters.BatSonarParams = handles.BatSonarParams;
            AllParameters.TerrainParams = handles.TerrainParams;
            AllParameters.PreyFlightParams = handles.PreyFlightParams;
            set(handles.textDataAvailabilty,'String','DATA on Progress');
            handles.DataToAnalyze = BatFlightForGui(AllParameters, handles.Terrain);
            
            set(hObject,'Enable','on');
            set(hObject,'String','RUN');
            set(handles.textDataAvailabilty,'String','DATA: from running Simulation'); %
            
            %%% Flight Summary and statitics for GUI
            SummaryString1 = ['Number Of Cataches: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanNumberOfCatches), ...
                '   (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDNumberOfCatches,3), ' )'];
            set(handles.textSummaryGroup1,'String',SummaryString1); %
            
            SummaryString2 = ['Number Of Prey Detections: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanNumberOfDetections,4), ...
                '   (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDNumberOfDetections,3), ' )'];
            set(handles.textSummaryGroup2,'String',SummaryString2); %
            
            SummaryString3 = ['Approach Masking Ratio Hunted: ',...
                num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanApproach_MaskingRatioToHunted,2), ...
                ' (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDApproach_MaskingRatioToHunted,2), ' )'];
            set(handles.textSummaryGroup3,'String',SummaryString3); %
            
            SummaryString4 = ['Buzz Masking Ratio Hunted: ', ...
                num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanBuzz_MaskingRatioToHunted,2), ...
                ' (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDBuzz_MaskingRatioToHunted,2), ' )'];
            set(handles.textSummaryGroup4,'String',SummaryString4); %
            
            SummaryString5 = ['Search Masking Ratio Hunted: ',...
                num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanSearch_MaskingRatioToHunted,2) ...
                ' (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDSearch_MaskingRatioToHunted,2), ' )'];
            set(handles.textSummaryGroup5,'String',SummaryString5); %
            
            % SummaryString6 = ['Interfernce while Search: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanInterferfenceWhileSearching,3)...
            %     '   (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDInterferfenceWhileSearching,3), ' )'];
            % set(handles.textSummaryGroup6,'String',SummaryString6); %
            
            SummaryString7 = ['Interfernce to Detection Ratio: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.TotalSearchAndApproachInterfernceRatio,3)];
            set(handles.textSummaryGroup7,'String',SummaryString7); %
            
            SummaryString8 = ['Interfernce to Hunted Ratio: ', ...
            num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanInterferenceToHuntedRatio,3)];
            set(handles.textSummaryGroup8,'String',SummaryString8); %
            %
            % SummaryString9 = ['Succesful Search Stages: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanNumberOfSuccesulSearchStages,3), ...
            %     '   (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDNumberOfSuccesulSearchStages,3), ' )'];
            % set(handles.textSummaryGroup9,'String',SummaryString9); %
            
            % SummaryString10 = ['Jamming  of Search Stages Ratio: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.JammedToSuccessfulSearchStagesRatio,3)];
            % set(handles.textSummaryGroup10,'String',SummaryString10); %
            %
            % SummaryString11 = ['Succesful Approach Stages: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.MeanNumberOfSuccesulApproachStages,3), ...
            %     '   (' , num2str(handles.DataToAnalyze.FlightInterferceSummary.Statistics.STDNumberOfSuccesulApproachStages,3), ' )'];
            % set(handles.textSummaryGroup11,'String',SummaryString11); %
            %
            % SummaryString12 = ['Jamming  of Approach Stages Ratio: ', num2str(handles.DataToAnalyze.FlightInterferceSummary.JammedToSuccessfulApproachStagesRatio,3)];
            % set(handles.textSummaryGroup12,'String',SummaryString12); %
            
            
            % SummaryString9 = ['Interfernce to Detection Ratio: ', num2str(handles.DataToAnalyze.FilghtInterferceSummary.InterferenceToHuntedRatio,3)];
            % set(handles.textSummaryGroup7,'String',SummaryString9); %
            
            guidata(hObject,handles);
        end

        % Button pushed function: pushbuttonSaveData
        function pushbuttonSaveData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSaveData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            BatDATA = handles.DataToAnalyze;
            
            StrDate = datestr(datetime('now'));
            SaveFileName = strrep(StrDate,' ','_');
            SaveFileName = strrep(SaveFileName,':','');
            SaveFileName = ['BatData_',SaveFileName,'.mat'];
            FileFilter = '*.mat';
            % [SaveFileName,PathName,FilterIndex] = uiputfile(['C:\Users\DELL\Documents\òåîø\ìéîåãéí\îç÷ø\MATLAB Bat Simulation\DATA\', SaveFileName],FileFilter);
            [SaveFileName,PathName,FilterIndex] = uiputfile(['DATA\', SaveFileName],FileFilter);
            % PathFileName = [PathName,FileName];
            
            % save(['C:\Users\DELL\Documents\òåîø\ìéîåãéí\îç÷ø\MATLAB Bat Simulation\DATA\',SaveFileName], 'BatDATA');
            save([PathName,SaveFileName], 'BatDATA');
        end

        % Button pushed function: pushbuttonSaveParameters
        function pushbuttonSaveParameters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSaveParameters (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SavePath = 'DATA\DefaultParameters';
            % SavePath = 'C:\Users\DELL\Documents\òåîø\ìéîåãéí\îç÷ø\MATLAB Bat Simulation\DATA\DefaultParameters';
            Params.SimParams = handles.SimParams;
            Params.BatFlight = handles.BatFlightParams;
            Params.BatSonar = handles.BatSonarParams;
            Params.Terrain= handles.TerrainParams;
            Params.PreyFlight = handles.PreyFlightParams;
            save(SavePath,'Params');
        end

        % Button pushed function: pushbuttonSendData
        function pushbuttonSendData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonSendData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            BatDATA = handles.DataToAnalyze;
            assignin('base','BatDATA',BatDATA);
        end

        % Clicked callback: uitoggletool5
        function uitoggletool5_ClickedCallback(app, event)
            % Enable or disable legend based on the
            % tool's current state.
            state = app.uitoggletool5.State;
            if state
                legend(gca);
            else
                legend(gca,'off');
            end
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.figure1, char(state));
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.figure1, char(state));
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BatGUI1';
            app.figure1.Tag = 'figure1';
            app.file_menu = uimenu(app.figure1);
            app.file_menu.Text = 'FIle';
            app.file_menu.Tag = 'file_menu';
            app.menu_file_open = uimenu(app.file_menu);
            app.menu_file_open.Text = 'Open';
            app.menu_file_open.Tag = 'menu_file_open';
            app.menu_file_flose = uimenu(app.file_menu);
            app.menu_file_flose.Checked = 'on';
            app.menu_file_flose.Text = 'Close';
            app.menu_file_flose.Tag = 'menu_file_flose';
            app.Untitled_3 = uimenu(app.file_menu);
            app.Untitled_3.Text = 'Save';
            app.Untitled_3.Tag = 'Untitled_3';
            app.menu_file_saveAs = uimenu(app.file_menu);
            app.menu_file_saveAs.Text = 'Save As';
            app.menu_file_saveAs.Tag = 'menu_file_saveAs';
            app.edit_menu = uimenu(app.figure1);
            app.edit_menu.Text = 'Edit';
            app.edit_menu.Tag = 'edit_menu';
            app.view_menu = uimenu(app.figure1);
            app.view_menu.Text = 'View';
            app.view_menu.Tag = 'view_menu';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Cursor';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Insert Legend';
            app.uitoggletool5.ClickedCallback = createCallbackFcn(app, @uitoggletool5_ClickedCallback, true);
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.uibuttongroup8 = uibuttongroup(app.figure1);
            app.uibuttongroup8.Title = 'Localization error ';
            app.uibuttongroup8.Tag = 'uibuttongroup8';
            app.text35 = uilabel(app.uibuttongroup8);
            app.text35.Tag = 'text35';
            app.text35.Text = {'Noise Level [dB-SPL]:'; ''};
            app.text63 = uilabel(app.uibuttongroup8);
            app.text63.Tag = 'text63';
            app.text63.Text = 'Detection TH [dB]:';
            app.text64 = uilabel(app.uibuttongroup8);
            app.text64.Tag = 'text64';
            app.text64.Text = 'ReceiverType:';
            app.popupmenuReceiverType = uidropdown(app.uibuttongroup8);
            app.popupmenuReceiverType.Items = {'Correlation', 'TimeSequenceTH', 'NoMasking', 'FilterBank', ''};
            app.popupmenuReceiverType.ValueChangedFcn = createCallbackFcn(app, @popupmenuReceiverType_Callback, true);
            app.popupmenuReceiverType.Tag = 'popupmenuReceiverType';
            app.popupmenuReceiverType.Value = 'NoMasking';
            app.text67 = uilabel(app.uibuttongroup8);
            app.text67.Tag = 'text67';
            app.text67.Text = 'SIR minimum TH';
            app.edit48_SIRneededTH = uieditfield(app.uibuttongroup8, 'text');
            app.edit48_SIRneededTH.ValueChangedFcn = createCallbackFcn(app, @edit48_SIRneededTH_Callback, true);
            app.edit48_SIRneededTH.Tag = 'edit48_SIRneededTH';
            app.edit48_SIRneededTH.Value = 'Edit Text';
            app.uibuttongroup11 = uibuttongroup(app.uibuttongroup8);
            app.uibuttongroup11.Title = 'JAR Mode';
            app.uibuttongroup11.Tag = 'uibuttongroup11';
            app.text78 = uilabel(app.uibuttongroup11);
            app.text78.Tag = 'text78';
            app.text78.Text = 'Delta [kHz]';
            app.text79 = uilabel(app.uibuttongroup11);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Repeat';
            app.edit_JARDelta = uieditfield(app.uibuttongroup11, 'text');
            app.edit_JARDelta.ValueChangedFcn = createCallbackFcn(app, @edit_JARDelta_Callback, true);
            app.edit_JARDelta.Tag = 'edit_JARDelta';
            app.edit_JARDelta.Value = '2';
            app.edit_JARmem = uieditfield(app.uibuttongroup11, 'text');
            app.edit_JARmem.ValueChangedFcn = createCallbackFcn(app, @edit_JARmem_Callback, true);
            app.edit_JARmem.Tag = 'edit_JARmem';
            app.edit_JARmem.Value = '5';
            app.checkbox_JARMode = uicheckbox(app.uibuttongroup11);
            app.checkbox_JARMode.ValueChangedFcn = createCallbackFcn(app, @checkbox_JARMode_Callback, true);
            app.checkbox_JARMode.Tag = 'checkbox_JARMode';
            app.checkbox_JARMode.Text = 'JAR Active';
            app.checkbox32_BiStatMode = uicheckbox(app.uibuttongroup8);
            app.checkbox32_BiStatMode.ValueChangedFcn = createCallbackFcn(app, @checkbox32_BiStatMode_Callback, true);
            app.checkbox32_BiStatMode.Tag = 'checkbox32_BiStatMode';
            app.checkbox32_BiStatMode.Text = 'Bi-Stat MODE';
            app.uibuttongroup12 = uibuttongroup(app.uibuttongroup8);
            app.uibuttongroup12.Title = 'Masking';
            app.uibuttongroup12.Tag = 'uibuttongroup12';
            app.checkbox22_FwdMaskingFlag = uicheckbox(app.uibuttongroup12);
            app.checkbox22_FwdMaskingFlag.ValueChangedFcn = createCallbackFcn(app, @checkbox22_FwdMaskingFlag_Callback, true);
            app.checkbox22_FwdMaskingFlag.Tag = 'checkbox22_FwdMaskingFlag';
            app.checkbox22_FwdMaskingFlag.Text = 'Fwd Masking';
            app.checkbox22_FwdMaskingFlag.Value = true;
            app.text69 = uilabel(app.uibuttongroup12);
            app.text69.Tag = 'text69';
            app.text69.Text = 'TH [dB]:';
            app.edit49_FwdMaskingTH = uieditfield(app.uibuttongroup12, 'text');
            app.edit49_FwdMaskingTH.ValueChangedFcn = createCallbackFcn(app, @edit49_FwdMaskingTH_Callback, true);
            app.edit49_FwdMaskingTH.Tag = 'edit49_FwdMaskingTH';
            app.edit49_FwdMaskingTH.Value = '0';
            app.text73 = uilabel(app.uibuttongroup12);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Time [ms]:';
            app.edit50_FwdMakingTime = uieditfield(app.uibuttongroup12, 'text');
            app.edit50_FwdMakingTime.ValueChangedFcn = createCallbackFcn(app, @edit50_FwdMakingTime_Callback, true);
            app.edit50_FwdMakingTime.Tag = 'edit50_FwdMakingTime';
            app.edit50_FwdMakingTime.Value = 'Edit Text';
            app.checkbox29_LocalizationErrFlag = uicheckbox(app.uibuttongroup12);
            app.checkbox29_LocalizationErrFlag.ValueChangedFcn = createCallbackFcn(app, @checkbox29_LocalizationErrFlag_Callback, true);
            app.checkbox29_LocalizationErrFlag.Tag = 'checkbox29_LocalizationErrFlag';
            app.checkbox29_LocalizationErrFlag.Text = 'Localization error';
            app.checkbox29_LocalizationErrFlag.Value = true;
            app.checkbox_MissDetectionProbFlag = uicheckbox(app.uibuttongroup12);
            app.checkbox_MissDetectionProbFlag.ValueChangedFcn = createCallbackFcn(app, @checkbox_MissDetectionProbFlag_Callback, true);
            app.checkbox_MissDetectionProbFlag.Tag = 'checkbox_MissDetectionProbFlag';
            app.checkbox_MissDetectionProbFlag.Text = 'PD flag';
            app.edit43_ProbToDetect = uieditfield(app.uibuttongroup12, 'text');
            app.edit43_ProbToDetect.ValueChangedFcn = createCallbackFcn(app, @edit43_ProbToDetect_Callback, true);
            app.edit43_ProbToDetect.Tag = 'edit43_ProbToDetect';
            app.edit43_ProbToDetect.Value = '1';
            app.edit58_PulseDetectionTH = uieditfield(app.uibuttongroup8, 'text');
            app.edit58_PulseDetectionTH.ValueChangedFcn = createCallbackFcn(app, @edit58_PulseDetectionTH_Callback, true);
            app.edit58_PulseDetectionTH.Tag = 'edit58_PulseDetectionTH';
            app.edit58_PulseDetectionTH.Value = 'Edit Text';
            app.checkbox33_ReactToBatsFlag = uicheckbox(app.uibuttongroup8);
            app.checkbox33_ReactToBatsFlag.ValueChangedFcn = createCallbackFcn(app, @checkbox33_ReactToBatsFlag_Callback, true);
            app.checkbox33_ReactToBatsFlag.Tag = 'checkbox33_ReactToBatsFlag';
            app.checkbox33_ReactToBatsFlag.Text = 'O.Bats React';
            app.edit59_NoiseLeveldB = uieditfield(app.uibuttongroup8, 'text');
            app.edit59_NoiseLeveldB.ValueChangedFcn = createCallbackFcn(app, @edit59_NoiseLeveldB_Callback, true);
            app.edit59_NoiseLeveldB.Tag = 'edit59_NoiseLeveldB';
            app.edit59_NoiseLeveldB.Value = '0';
            app.uibuttongroup13 = uibuttongroup(app.uibuttongroup8);
            app.uibuttongroup13.Tag = 'uibuttongroup13';
            app.checkbox34_PhantomEchoesFromConsFlag = uicheckbox(app.uibuttongroup13);
            app.checkbox34_PhantomEchoesFromConsFlag.ValueChangedFcn = createCallbackFcn(app, @checkbox34_PhantomEchoesFromConsFlag_Callback, true);
            app.checkbox34_PhantomEchoesFromConsFlag.Tag = 'checkbox34_PhantomEchoesFromConsFlag';
            app.checkbox34_PhantomEchoesFromConsFlag.Text = 'Phantoms ';
            app.checkbox35_PhantomCheckSim = uicheckbox(app.uibuttongroup13);
            app.checkbox35_PhantomCheckSim.ValueChangedFcn = createCallbackFcn(app, @checkbox35_PhantomCheckSim_Callback, true);
            app.checkbox35_PhantomCheckSim.Tag = 'checkbox35_PhantomCheckSim';
            app.checkbox35_PhantomCheckSim.Text = 'Check Sim';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'Data';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.pushbuttonRunSim = uibutton(app.uibuttongroup5, 'push');
            app.pushbuttonRunSim.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRunSim_Callback, true);
            app.pushbuttonRunSim.Tag = 'pushbuttonRunSim';
            app.pushbuttonRunSim.Text = 'RUN';
            app.pushbuttonSendData = uibutton(app.uibuttongroup5, 'push');
            app.pushbuttonSendData.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSendData_Callback, true);
            app.pushbuttonSendData.Tag = 'pushbuttonSendData';
            app.pushbuttonSendData.Text = 'Send to WS';
            app.pushbuttonLoadData = uibutton(app.uibuttongroup5, 'push');
            app.pushbuttonLoadData.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLoadData_Callback, true);
            app.pushbuttonLoadData.Tag = 'pushbuttonLoadData';
            app.pushbuttonLoadData.Text = 'Load DATA';
            app.pushbuttonSaveData = uibutton(app.uibuttongroup5, 'push');
            app.pushbuttonSaveData.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSaveData_Callback, true);
            app.pushbuttonSaveData.Tag = 'pushbuttonSaveData';
            app.pushbuttonSaveData.Text = 'Save DATA';
            app.textDataAvailabilty = uilabel(app.uibuttongroup5);
            app.textDataAvailabilty.Tag = 'textDataAvailabilty';
            app.textDataAvailabilty.Text = {'DATA:'; ''};
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Simulation Analysis';
            app.uipanel7.Tag = 'uipanel7';
            app.TimePlot = uiaxes(app.uipanel7);
            app.TimePlot.Tag = 'TimePlot';
            app.xyPlot = uiaxes(app.uipanel7);
            app.xyPlot.Tag = 'xyPlot';
            app.checkboxTimeHoldOn = uicheckbox(app.uipanel7);
            app.checkboxTimeHoldOn.ValueChangedFcn = createCallbackFcn(app, @checkboxTimeHoldOn_Callback, true);
            app.checkboxTimeHoldOn.Tag = 'checkboxTimeHoldOn';
            app.checkboxTimeHoldOn.Text = 'hold on';
            app.checkboxTimeHoldOn.Value = true;
            app.pushbuttonPlotxy = uibutton(app.uipanel7, 'push');
            app.pushbuttonPlotxy.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlotxy_Callback, true);
            app.pushbuttonPlotxy.Tag = 'pushbuttonPlotxy';
            app.pushbuttonPlotxy.Text = {'Plot X-Y    '; '                '};
            app.checkboxHoldOn = uicheckbox(app.uipanel7);
            app.checkboxHoldOn.ValueChangedFcn = createCallbackFcn(app, @checkboxHoldOn_Callback, true);
            app.checkboxHoldOn.Tag = 'checkboxHoldOn';
            app.checkboxHoldOn.Text = 'hold on';
            app.checkboxHoldOn.Value = true;
            app.pushbuttonClearFigxy = uibutton(app.uipanel7, 'push');
            app.pushbuttonClearFigxy.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClearFigxy_Callback, true);
            app.pushbuttonClearFigxy.Tag = 'pushbuttonClearFigxy';
            app.pushbuttonClearFigxy.Text = 'clear figure';
            app.pushbuttonPlotTimeGraph = uibutton(app.uipanel7, 'push');
            app.pushbuttonPlotTimeGraph.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlotTimeGraph_Callback, true);
            app.pushbuttonPlotTimeGraph.Tag = 'pushbuttonPlotTimeGraph';
            app.pushbuttonPlotTimeGraph.Text = {'Plot time-Graph'; ''};
            app.pushbuttonClearTimeFig = uibutton(app.uipanel7, 'push');
            app.pushbuttonClearTimeFig.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonClearTimeFig_Callback, true);
            app.pushbuttonClearTimeFig.Tag = 'pushbuttonClearTimeFig';
            app.pushbuttonClearTimeFig.Text = 'clear figure';
            app.uibuttongroup4 = uibuttongroup(app.uipanel7);
            app.uibuttongroup4.Title = 'Time Filter';
            app.uibuttongroup4.Tag = 'uibuttongroup4';
            app.checkboxTimeFilter = uicheckbox(app.uibuttongroup4);
            app.checkboxTimeFilter.ValueChangedFcn = createCallbackFcn(app, @checkboxTimeFilter_Callback, true);
            app.checkboxTimeFilter.Tag = 'checkboxTimeFilter';
            app.checkboxTimeFilter.Text = 'ON';
            app.editStartTime = uieditfield(app.uibuttongroup4, 'text');
            app.editStartTime.ValueChangedFcn = createCallbackFcn(app, @editStartTime_Callback, true);
            app.editStartTime.Tag = 'editStartTime';
            app.editStartTime.Value = '0';
            app.text22 = uilabel(app.uibuttongroup4);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Start';
            app.text23 = uilabel(app.uibuttongroup4);
            app.text23.Tag = 'text23';
            app.text23.Text = 'End';
            app.editEndTime = uieditfield(app.uibuttongroup4, 'text');
            app.editEndTime.ValueChangedFcn = createCallbackFcn(app, @editEndTime_Callback, true);
            app.editEndTime.Tag = 'editEndTime';
            app.editEndTime.Value = '20';
            app.pushbuttonRunAnimation = uibutton(app.uipanel7, 'push');
            app.pushbuttonRunAnimation.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonRunAnimation_Callback, true);
            app.pushbuttonRunAnimation.Tag = 'pushbuttonRunAnimation';
            app.pushbuttonRunAnimation.Text = {'RUN animation'; '                 '};
            app.uibuttongroup6 = uibuttongroup(app.uipanel7);
            app.uibuttongroup6.Title = 'Bats Plot';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.checkboxAllBatsPlot = uicheckbox(app.uibuttongroup6);
            app.checkboxAllBatsPlot.ValueChangedFcn = createCallbackFcn(app, @checkboxAllBatsPlot_Callback, true);
            app.checkboxAllBatsPlot.Tag = 'checkboxAllBatsPlot';
            app.checkboxAllBatsPlot.Text = {'All Bats'; ''};
            app.text33 = uilabel(app.uibuttongroup6);
            app.text33.Tag = 'text33';
            app.text33.Text = {'Plot Bat:'; ''};
            app.edit18BatNumToPlot = uieditfield(app.uibuttongroup6, 'text');
            app.edit18BatNumToPlot.ValueChangedFcn = createCallbackFcn(app, @edit18BatNumToPlot_Callback, true);
            app.edit18BatNumToPlot.Tag = 'edit18BatNumToPlot';
            app.edit18BatNumToPlot.Value = '1';
            app.uibuttongroup7 = uibuttongroup(app.uipanel7);
            app.uibuttongroup7.Title = 'Preys Plot';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.checkbox19AllPreysPlot = uicheckbox(app.uibuttongroup7);
            app.checkbox19AllPreysPlot.ValueChangedFcn = createCallbackFcn(app, @checkbox19AllPreysPlot_Callback, true);
            app.checkbox19AllPreysPlot.Tag = 'checkbox19AllPreysPlot';
            app.checkbox19AllPreysPlot.Text = {'All Preys'; ''};
            app.text34 = uilabel(app.uibuttongroup7);
            app.text34.Tag = 'text34';
            app.text34.Text = {'Plot Prey:'; '           '};
            app.edit40PreyNumToPlot = uieditfield(app.uibuttongroup7, 'text');
            app.edit40PreyNumToPlot.ValueChangedFcn = createCallbackFcn(app, @edit40PreyNumToPlot_Callback, true);
            app.edit40PreyNumToPlot.Tag = 'edit40PreyNumToPlot';
            app.edit40PreyNumToPlot.Value = '1';
            app.uipanel11 = uipanel(app.uipanel7);
            app.uipanel11.Title = 'Legend';
            app.uipanel11.Tag = 'uipanel11';
            app.text39 = uilabel(app.uipanel11);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Search';
            app.text40 = uilabel(app.uipanel11);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Approach';
            app.text41 = uilabel(app.uipanel11);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Obstacle Man';
            app.text44 = uilabel(app.uipanel11);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Static Text';
            app.text45 = uilabel(app.uipanel11);
            app.text45.Tag = 'text45';
            app.text45.Text = '+';
            app.text42 = uilabel(app.uipanel11);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Catching Prey: ';
            app.text46 = uilabel(app.uipanel11);
            app.text46.Tag = 'text46';
            app.text46.Text = 'O';
            app.text43 = uilabel(app.uipanel11);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Interference:';
            app.uibuttongroup9 = uibuttongroup(app.uipanel7);
            app.uibuttongroup9.Title = 'Fight Summary: mean (std)';
            app.uibuttongroup9.Tag = 'uibuttongroup9';
            app.textSummaryGroup1 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup1.Tag = 'textSummaryGroup1';
            app.textSummaryGroup1.Text = 'Total Catches:';
            app.textSummaryGroup2 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup2.Tag = 'textSummaryGroup2';
            app.textSummaryGroup2.Text = 'Text2';
            app.textSummaryGroup3 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup3.Tag = 'textSummaryGroup3';
            app.textSummaryGroup3.Text = 'Text3';
            app.textSummaryGroup4 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup4.Tag = 'textSummaryGroup4';
            app.textSummaryGroup4.Text = 'Text4';
            app.textSummaryGroup5 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup5.Tag = 'textSummaryGroup5';
            app.textSummaryGroup5.Text = 'Text5';
            app.textSummaryGroup6 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup6.Tag = 'textSummaryGroup6';
            app.textSummaryGroup6.Text = 'Text6';
            app.textSummaryGroup7 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup7.Tag = 'textSummaryGroup7';
            app.textSummaryGroup7.Text = ' Text7';
            app.textSummaryGroup8 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup8.Tag = 'textSummaryGroup8';
            app.textSummaryGroup8.Text = 'Text8';
            app.textSummaryGroup9 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup9.Tag = 'textSummaryGroup9';
            app.textSummaryGroup9.Text = 'Text9';
            app.textSummaryGroup11 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup11.Tag = 'textSummaryGroup11';
            app.textSummaryGroup11.Text = ' Text11';
            app.textSummaryGroup12 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup12.Tag = 'textSummaryGroup12';
            app.textSummaryGroup12.Text = 'Text12';
            app.textSummaryGroup10 = uilabel(app.uibuttongroup9);
            app.textSummaryGroup10.Tag = 'textSummaryGroup10';
            app.textSummaryGroup10.Text = 'text10';
            app.pushbuttonMyBatUmweltPlot = uibutton(app.uibuttongroup9, 'push');
            app.pushbuttonMyBatUmweltPlot.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonMyBatUmweltPlot_Callback, true);
            app.pushbuttonMyBatUmweltPlot.Tag = 'pushbuttonMyBatUmweltPlot';
            app.pushbuttonMyBatUmweltPlot.Text = 'BatUmwelt Plot';
            app.pushbuttonPlotSonogram = uibutton(app.uibuttongroup9, 'push');
            app.pushbuttonPlotSonogram.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonPlotSonogram_Callback, true);
            app.pushbuttonPlotSonogram.Tag = 'pushbuttonPlotSonogram';
            app.pushbuttonPlotSonogram.Text = 'Run Sonogram';
            app.uibuttongroup10 = uibuttongroup(app.uipanel7);
            app.uibuttongroup10.Title = 'Run Experiment';
            app.uibuttongroup10.Tag = 'uibuttongroup10';
            app.pushbutton17_ChooseTest = uibutton(app.uibuttongroup10, 'push');
            app.pushbutton17_ChooseTest.Tag = 'pushbutton17_ChooseTest';
            app.pushbutton17_ChooseTest.Text = 'Choose File';
            app.pushbutton18_ChooseSaveDir = uibutton(app.uibuttongroup10, 'push');
            app.pushbutton18_ChooseSaveDir.Tag = 'pushbutton18_ChooseSaveDir';
            app.pushbutton18_ChooseSaveDir.Text = 'Save Dir. ';
            app.pushbutton20_RunExp = uibutton(app.uibuttongroup10, 'push');
            app.pushbutton20_RunExp.ButtonPushedFcn = createCallbackFcn(app, @pushbutton20_RunExp_Callback, true);
            app.pushbutton20_RunExp.Tag = 'pushbutton20_RunExp';
            app.pushbutton20_RunExp.Text = 'RUN Exp';
            app.text60_ScriptName = uilabel(app.uibuttongroup10);
            app.text60_ScriptName.Tag = 'text60_ScriptName';
            app.text60_ScriptName.Text = 'script';
            app.text61_SaveDirectory = uilabel(app.uibuttongroup10);
            app.text61_SaveDirectory.Tag = 'text61_SaveDirectory';
            app.text61_SaveDirectory.Text = 'Directory';
            app.text62 = uilabel(app.uibuttongroup10);
            app.text62.Tag = 'text62';
            app.text62.Text = 'Num of Trials:';
            app.edit_NumOfTrials = uieditfield(app.uibuttongroup10, 'text');
            app.edit_NumOfTrials.Tag = 'edit_NumOfTrials';
            app.edit_NumOfTrials.Value = '1';
            app.checkboxSaveAnimation = uicheckbox(app.uipanel7);
            app.checkboxSaveAnimation.ValueChangedFcn = createCallbackFcn(app, @checkboxSaveAnimation_Callback, true);
            app.checkboxSaveAnimation.Tag = 'checkboxSaveAnimation';
            app.checkboxSaveAnimation.Text = 'Save Animation';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Sonar Parameters ';
            app.uipanel6.Tag = 'uipanel6';
            app.text14 = uilabel(app.uipanel6);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Beam Width [rad]:';
            app.text15 = uilabel(app.uipanel6);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Detect Range [m]:';
            app.text16 = uilabel(app.uipanel6);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Pulse Power [dB- SPL]:';
            app.text17 = uilabel(app.uipanel6);
            app.text17.Tag = 'text17';
            app.text17.Text = 'PRF min max [1/s]:';
            app.text18 = uilabel(app.uipanel6);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Pulse Time  min max [s]:';
            app.text19 = uilabel(app.uipanel6);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Center Freq [kHz]:';
            app.editBeamWidth = uieditfield(app.uipanel6, 'text');
            app.editBeamWidth.ValueChangedFcn = createCallbackFcn(app, @editBeamWidth_Callback, true);
            app.editBeamWidth.Tag = 'editBeamWidth';
            app.editBeamWidth.Value = 'pi/6';
            app.editDetectRange = uieditfield(app.uipanel6, 'text');
            app.editDetectRange.ValueChangedFcn = createCallbackFcn(app, @editDetectRange_Callback, true);
            app.editDetectRange.Tag = 'editDetectRange';
            app.editDetectRange.Value = '2';
            app.editTransmitPower = uieditfield(app.uipanel6, 'text');
            app.editTransmitPower.ValueChangedFcn = createCallbackFcn(app, @editTransmitPower_Callback, true);
            app.editTransmitPower.Tag = 'editTransmitPower';
            app.editTransmitPower.Value = '8';
            app.editPRFMin = uieditfield(app.uipanel6, 'text');
            app.editPRFMin.ValueChangedFcn = createCallbackFcn(app, @editPRFMin_Callback, true);
            app.editPRFMin.Tag = 'editPRFMin';
            app.editPRFMin.Value = '20';
            app.editPRFMax = uieditfield(app.uipanel6, 'text');
            app.editPRFMax.ValueChangedFcn = createCallbackFcn(app, @editPRFMax_Callback, true);
            app.editPRFMax.Tag = 'editPRFMax';
            app.editPRFMax.Value = '100';
            app.editPulseTimeMin = uieditfield(app.uipanel6, 'text');
            app.editPulseTimeMin.ValueChangedFcn = createCallbackFcn(app, @editPulseTimeMin_Callback, true);
            app.editPulseTimeMin.Tag = 'editPulseTimeMin';
            app.editPulseTimeMin.Value = '3';
            app.editPulseTimeMax = uieditfield(app.uipanel6, 'text');
            app.editPulseTimeMax.ValueChangedFcn = createCallbackFcn(app, @editPulseTimeMax_Callback, true);
            app.editPulseTimeMax.Tag = 'editPulseTimeMax';
            app.editPulseTimeMax.Value = '10';
            app.text29 = uilabel(app.uipanel6);
            app.text29.Tag = 'text29';
            app.text29.Text = 'ChirpSpan [kHz]:';
            app.text74 = uilabel(app.uipanel6);
            app.text74.Tag = 'text74';
            app.text74.Text = 'min Pulse Power (Buzz): ';
            app.edit51_MinPulsePower = uieditfield(app.uipanel6, 'text');
            app.edit51_MinPulsePower.ValueChangedFcn = createCallbackFcn(app, @edit51_MinPulsePower_Callback, true);
            app.edit51_MinPulsePower.Tag = 'edit51_MinPulsePower';
            app.edit51_MinPulsePower.Value = 'Edit Text';
            app.text75 = uilabel(app.uipanel6);
            app.text75.Tag = 'text75';
            app.text75.Text = 'Dist to Buzz[m]:';
            app.text76 = uilabel(app.uipanel6);
            app.text76.Tag = 'text76';
            app.text76.Text = 'Dist to Approach: [m]';
            app.edit52_Dist2Buzz = uieditfield(app.uipanel6, 'text');
            app.edit52_Dist2Buzz.ValueChangedFcn = createCallbackFcn(app, @edit52_Dist2Buzz_Callback, true);
            app.edit52_Dist2Buzz.Tag = 'edit52_Dist2Buzz';
            app.edit52_Dist2Buzz.Value = 'Edit Text';
            app.edit53_Dist2Appraoch = uieditfield(app.uipanel6, 'text');
            app.edit53_Dist2Appraoch.ValueChangedFcn = createCallbackFcn(app, @edit53_Dist2Appraoch_Callback, true);
            app.edit53_Dist2Appraoch.Tag = 'edit53_Dist2Appraoch';
            app.edit53_Dist2Appraoch.Value = 'Edit Text';
            app.checkboxVarianceSonar = uicheckbox(app.uipanel6);
            app.checkboxVarianceSonar.Tag = 'checkboxVarianceSonar';
            app.checkboxVarianceSonar.Text = 'Variance';
            app.checkboxChirp = uicheckbox(app.uipanel6);
            app.checkboxChirp.ValueChangedFcn = createCallbackFcn(app, @checkboxChirp_Callback, true);
            app.checkboxChirp.Tag = 'checkboxChirp';
            app.checkboxChirp.Text = 'Chirp';
            app.checkboxChirp.Value = true;
            app.editChirpSpan = uieditfield(app.uipanel6, 'text');
            app.editChirpSpan.ValueChangedFcn = createCallbackFcn(app, @editChirpSpan_Callback, true);
            app.editChirpSpan.Tag = 'editChirpSpan';
            app.editChirpSpan.Value = '4';
            app.editCenterFreq = uieditfield(app.uipanel6, 'text');
            app.editCenterFreq.ValueChangedFcn = createCallbackFcn(app, @editCenterFreq_Callback, true);
            app.editCenterFreq.Tag = 'editCenterFreq';
            app.editCenterFreq.Value = '80';
            app.edit60_ClutterGainDB = uieditfield(app.uipanel6, 'text');
            app.edit60_ClutterGainDB.ValueChangedFcn = createCallbackFcn(app, @edit60_ClutterGainDB_Callback, true);
            app.edit60_ClutterGainDB.Tag = 'edit60_ClutterGainDB';
            app.edit60_ClutterGainDB.Value = '0';
            app.text81 = uilabel(app.uipanel6);
            app.text81.Tag = 'text81';
            app.text81.Text = 'C GainDB:';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Bat Filght Parameters ';
            app.uipanel9.Tag = 'uipanel9';
            app.text10 = uilabel(app.uipanel9);
            app.text10.Tag = 'text10';
            app.text10.Text = {'Nominal Vel. [m/s]:'; '                '};
            app.text11 = uilabel(app.uipanel9);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Max Accel [m/s2] :             ';
            app.editMaxAccel = uieditfield(app.uipanel9, 'text');
            app.editMaxAccel.ValueChangedFcn = createCallbackFcn(app, @editMaxAccel_Callback, true);
            app.editMaxAccel.Tag = 'editMaxAccel';
            app.editMaxAccel.Value = '20';
            app.editNominalVelocity = uieditfield(app.uipanel9, 'text');
            app.editNominalVelocity.ValueChangedFcn = createCallbackFcn(app, @editNominalVelocity_Callback, true);
            app.editNominalVelocity.Tag = 'editNominalVelocity';
            app.editNominalVelocity.Value = '20';
            app.text12 = uilabel(app.uipanel9);
            app.text12.Tag = 'text12';
            app.text12.Text = {'Max Velocity [m/s]:'; '                '};
            app.editMaxVelocity = uieditfield(app.uipanel9, 'text');
            app.editMaxVelocity.ValueChangedFcn = createCallbackFcn(app, @editMaxVelocity_Callback, true);
            app.editMaxVelocity.Tag = 'editMaxVelocity';
            app.editMaxVelocity.Value = '0.001';
            app.text13 = uilabel(app.uipanel9);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Nominal Accel [m/s2] :             ';
            app.popupmenuFlightType = uidropdown(app.uipanel9);
            app.popupmenuFlightType.Items = {'random', 'linear', 'circle', '      '};
            app.popupmenuFlightType.ValueChangedFcn = createCallbackFcn(app, @popupmenuFlightType_Callback, true);
            app.popupmenuFlightType.Tag = 'popupmenuFlightType';
            app.popupmenuFlightType.Value = 'random';
            app.editNominalAccel = uieditfield(app.uipanel9, 'text');
            app.editNominalAccel.ValueChangedFcn = createCallbackFcn(app, @editNominalAccel_Callback, true);
            app.editNominalAccel.Tag = 'editNominalAccel';
            app.editNominalAccel.Value = '10';
            app.text77 = uilabel(app.uipanel9);
            app.text77.Tag = 'text77';
            app.text77.Text = {'Min Velocity'; ''};
            app.edit54_BatMinVelocity = uieditfield(app.uipanel9, 'text');
            app.edit54_BatMinVelocity.ValueChangedFcn = createCallbackFcn(app, @edit54_BatMinVelocity_Callback, true);
            app.edit54_BatMinVelocity.Tag = 'edit54_BatMinVelocity';
            app.edit54_BatMinVelocity.Value = '1';
            app.pushbuttonLoadDefaultParams = uibutton(app.figure1, 'push');
            app.pushbuttonLoadDefaultParams.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonLoadDefaultParams_Callback, true);
            app.pushbuttonLoadDefaultParams.Tag = 'pushbuttonLoadDefaultParams';
            app.pushbuttonLoadDefaultParams.Text = 'Load Default Params';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'Prey Flight Parameters';
            app.uipanel10.Tag = 'uipanel10';
            app.text24 = uilabel(app.uipanel10);
            app.text24.Tag = 'text24';
            app.text24.Text = {'Nominal Vel. [m/s]:'; '                '};
            app.text25 = uilabel(app.uipanel10);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Max Accel [m/s2] :             ';
            app.text26 = uilabel(app.uipanel10);
            app.text26.Tag = 'text26';
            app.text26.Text = {'Max Velocity [m/s]:'; '                '};
            app.text27 = uilabel(app.uipanel10);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Nominal Accel [m/s2] :             ';
            app.popupmenuPreyFlightType = uidropdown(app.uipanel10);
            app.popupmenuPreyFlightType.Items = {'random', 'linear', 'circle', 'static'};
            app.popupmenuPreyFlightType.ValueChangedFcn = createCallbackFcn(app, @popupmenuPreyFlightType_Callback, true);
            app.popupmenuPreyFlightType.Tag = 'popupmenuPreyFlightType';
            app.popupmenuPreyFlightType.Value = 'random';
            app.editPreyMaxVelocity = uieditfield(app.uipanel10, 'text');
            app.editPreyMaxVelocity.ValueChangedFcn = createCallbackFcn(app, @editPreyMaxVelocity_Callback, true);
            app.editPreyMaxVelocity.Tag = 'editPreyMaxVelocity';
            app.editPreyMaxVelocity.Value = '0';
            app.editPreyNominalVelocity = uieditfield(app.uipanel10, 'text');
            app.editPreyNominalVelocity.ValueChangedFcn = createCallbackFcn(app, @editPreyNominalVelocity_Callback, true);
            app.editPreyNominalVelocity.Tag = 'editPreyNominalVelocity';
            app.editPreyNominalVelocity.Value = '0.5';
            app.editPreyNominalAccel = uieditfield(app.uipanel10, 'text');
            app.editPreyNominalAccel.ValueChangedFcn = createCallbackFcn(app, @editPreyNominalAccel_Callback, true);
            app.editPreyNominalAccel.Tag = 'editPreyNominalAccel';
            app.editPreyNominalAccel.Value = '0.5';
            app.editPreyMaxAccelaration = uieditfield(app.uipanel10, 'text');
            app.editPreyMaxAccelaration.ValueChangedFcn = createCallbackFcn(app, @editPreyMaxAccelaration_Callback, true);
            app.editPreyMaxAccelaration.Tag = 'editPreyMaxAccelaration';
            app.editPreyMaxAccelaration.Value = '1';
            app.text36 = uilabel(app.uipanel10);
            app.text36.Tag = 'text36';
            app.text36.Text = {'Target winglength[m]:'; '                     '};
            app.edit42TargetWingLength = uieditfield(app.uipanel10, 'text');
            app.edit42TargetWingLength.ValueChangedFcn = createCallbackFcn(app, @edit42TargetWingLength_Callback, true);
            app.edit42TargetWingLength.Tag = 'edit42TargetWingLength';
            app.edit42TargetWingLength.Value = '0.02';
            app.checkboxIsPrey = uicheckbox(app.uipanel10);
            app.checkboxIsPrey.ValueChangedFcn = createCallbackFcn(app, @checkboxIsPrey_Callback, true);
            app.checkboxIsPrey.Tag = 'checkboxIsPrey';
            app.checkboxIsPrey.Text = 'Prey movement';
            app.checkboxIsPrey.Value = true;
            app.pushbuttonSaveParameters = uibutton(app.figure1, 'push');
            app.pushbuttonSaveParameters.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonSaveParameters_Callback, true);
            app.pushbuttonSaveParameters.Tag = 'pushbuttonSaveParameters';
            app.pushbuttonSaveParameters.Text = 'Save Parameters';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Enviroment';
            app.uipanel2.Tag = 'uipanel2';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = {'room size x,y,z [m]:'; '                '};
            app.editXroom = uieditfield(app.uipanel2, 'text');
            app.editXroom.ValueChangedFcn = createCallbackFcn(app, @editXroom_Callback, true);
            app.editXroom.Tag = 'editXroom';
            app.editXroom.Value = '20';
            app.editZroom = uieditfield(app.uipanel2, 'text');
            app.editZroom.ValueChangedFcn = createCallbackFcn(app, @editZroom_Callback, true);
            app.editZroom.Tag = 'editZroom';
            app.editZroom.Value = '20';
            app.editYroom = uieditfield(app.uipanel2, 'text');
            app.editYroom.ValueChangedFcn = createCallbackFcn(app, @editYroom_Callback, true);
            app.editYroom.Tag = 'editYroom';
            app.editYroom.Value = '20';
            app.popupmenuEnvironment = uidropdown(app.uipanel2);
            app.popupmenuEnvironment.Items = {'Empty Room', 'Free Space', 'Room1', 'Room2', 'Star'};
            app.popupmenuEnvironment.ValueChangedFcn = createCallbackFcn(app, @popupmenuEnvironment_Callback, true);
            app.popupmenuEnvironment.Tag = 'popupmenuEnvironment';
            app.popupmenuEnvironment.Value = 'Empty Room';
            app.text82 = uilabel(app.uipanel2);
            app.text82.Tag = 'text82';
            app.text82.Text = 'Swarm STD [m]:';
            app.edit_swarm_start_pos_std = uieditfield(app.uipanel2, 'text');
            app.edit_swarm_start_pos_std.ValueChangedFcn = createCallbackFcn(app, @edit_swarm_start_pos_std_Callback, true);
            app.edit_swarm_start_pos_std.Tag = 'edit_swarm_start_pos_std';
            app.edit_swarm_start_pos_std.Value = 'Edit Text';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Simulation Parameters ';
            app.uipanel8.Tag = 'uipanel8';
            app.text8 = uilabel(app.uipanel8);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Total Time [s]:'; '                '};
            app.text9 = uilabel(app.uipanel8);
            app.text9.Tag = 'text9';
            app.text9.Text = {'Sample Time [s]:'; '                '};
            app.editSimulationTime = uieditfield(app.uipanel8, 'text');
            app.editSimulationTime.ValueChangedFcn = createCallbackFcn(app, @editSimulationTime_Callback, true);
            app.editSimulationTime.Tag = 'editSimulationTime';
            app.editSimulationTime.Value = '0';
            app.editSampleTime = uieditfield(app.uipanel8, 'text');
            app.editSampleTime.ValueChangedFcn = createCallbackFcn(app, @editSampleTime_Callback, true);
            app.editSampleTime.Tag = 'editSampleTime';
            app.editSampleTime.Value = '5';
            app.text7 = uilabel(app.uipanel8);
            app.text7.Tag = 'text7';
            app.text7.Text = {'Resolution [m]:'; '                '};
            app.editXYRes = uieditfield(app.uipanel8, 'text');
            app.editXYRes.ValueChangedFcn = createCallbackFcn(app, @editXYRes_Callback, true);
            app.editXYRes.Tag = 'editXYRes';
            app.editXYRes.Value = '0';
            app.text31 = uilabel(app.uipanel8);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Number Of Bats :';
            app.editBatsNumber = uieditfield(app.uipanel8, 'text');
            app.editBatsNumber.ValueChangedFcn = createCallbackFcn(app, @editBatsNumber_Callback, true);
            app.editBatsNumber.Tag = 'editBatsNumber';
            app.editBatsNumber.Value = '1';
            app.text32 = uilabel(app.uipanel8);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Number Of Preys :';
            app.editPreysNumber = uieditfield(app.uipanel8, 'text');
            app.editPreysNumber.ValueChangedFcn = createCallbackFcn(app, @editPreysNumber_Callback, true);
            app.editPreysNumber.Tag = 'editPreysNumber';
            app.editPreysNumber.Value = '1';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Title = 'Bat Behavior';
            app.uipanel13.Tag = 'uipanel13';
            app.checkboxAvoidObs = uicheckbox(app.uipanel13);
            app.checkboxAvoidObs.ValueChangedFcn = createCallbackFcn(app, @checkboxAvoidObs_Callback, true);
            app.checkboxAvoidObs.Tag = 'checkboxAvoidObs';
            app.checkboxAvoidObs.Text = 'Avoid Obtacles';
            app.checkboxAvoidObs.Value = true;
            app.popupmenu_TestMode = uidropdown(app.uipanel13);
            app.popupmenu_TestMode.Items = {'foraging', 'swarm'};
            app.popupmenu_TestMode.ValueChangedFcn = createCallbackFcn(app, @popupmenu_TestMode_Callback, true);
            app.popupmenu_TestMode.Tag = 'popupmenu_TestMode';
            app.popupmenu_TestMode.Value = 'foraging';
            app.text83 = uilabel(app.uipanel13);
            app.text83.Tag = 'text83';
            app.text83.Text = 'Mode:';
            app.text84 = uilabel(app.uipanel13);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Detections:';
            app.uibuttongroup14 = uibuttongroup(app.uipanel13);
            app.uibuttongroup14.Title = 'Masking:';
            app.uibuttongroup14.Tag = 'uibuttongroup14';
            app.checkbox37_MaskingByConsps = uicheckbox(app.uibuttongroup14);
            app.checkbox37_MaskingByConsps.ValueChangedFcn = createCallbackFcn(app, @checkbox37_MaskingByConsps_Callback, true);
            app.checkbox37_MaskingByConsps.Tag = 'checkbox37_MaskingByConsps';
            app.checkbox37_MaskingByConsps.Text = 'Consps';
            app.checkbox37_MaskingByConsps.Value = true;
            app.checkbox38_MaskingByClutter = uicheckbox(app.uibuttongroup14);
            app.checkbox38_MaskingByClutter.ValueChangedFcn = createCallbackFcn(app, @checkbox38_MaskingByClutter_Callback, true);
            app.checkbox38_MaskingByClutter.Tag = 'checkbox38_MaskingByClutter';
            app.checkbox38_MaskingByClutter.Text = 'Clutter';
            app.popupmenu10_DetetctBehavior = uidropdown(app.uipanel13);
            app.popupmenu10_DetetctBehavior.Items = {'Prey', 'Conspecifics', 'Obstacles'};
            app.popupmenu10_DetetctBehavior.ValueChangedFcn = createCallbackFcn(app, @popupmenu10_DetetctBehavior_Callback, true);
            app.popupmenu10_DetetctBehavior.Tag = 'popupmenu10_DetetctBehavior';
            app.popupmenu10_DetetctBehavior.Value = 'Prey';
            app.uitable2 = uitable(app.figure1);
            app.uitable2.Tag = 'uitable2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatGUI1_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)BatGUI1_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end